classdef app_for_pendulum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        CheckBox          matlab.ui.control.CheckBox
        ProgrammName      matlab.ui.control.Label
        by_FORVARD        matlab.ui.control.Image
        Start             matlab.ui.control.Button
        sinAxTButton      matlab.ui.control.Button
        Pause             matlab.ui.control.Button
        SettisgSin        matlab.ui.control.Button
        TEditField        matlab.ui.control.NumericEditField
        TLabel            matlab.ui.control.Label
        AEditField        matlab.ui.control.NumericEditField
        ALabel            matlab.ui.control.Label
        Settings          matlab.ui.control.Label
        DEditField        matlab.ui.control.NumericEditField
        DEditFieldLabel   matlab.ui.control.Label
        IEditField        matlab.ui.control.NumericEditField
        IEditFieldLabel   matlab.ui.control.Label
        PIDSetting        matlab.ui.control.Label
        PEditField        matlab.ui.control.NumericEditField
        PEditFieldLabel   matlab.ui.control.Label
        SetPID            matlab.ui.control.Button
        AxesOutput        matlab.ui.control.UIAxes
        AxesInputIncoder  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        isPaused logical = false % Description
        isSinactived logical = false  
        %Timer

    end
    
    properties (Access = private)
        Timer % Description инициализируем таймер 
        tDatareal double = [ 0]; % Хранит значения времени
        ADatareal double = [ 0]; % Хранит значения углов
        tDatasinzad double = [0]; % Хранит значения времени
        ADatasynzad double = [0]; % Хранит значения углов
        AMP double = 0;
        c double = 0;
        T double = 0;
        timework double = 0;
        sinA double = [0];
        diskretTime  double = 0.03;
        t double = [ 0];
        data double = [0];
        u_datareal uint32 = [0 0];
        error_datareal double = [0 0];
        s ;
    end
    
    methods (Access = private)
        function figureupdate(app, ~, ~)
            %stop(app.WindSpeedTimer);
            if ~app.isPaused %&& ishandle(app.AxesInputIncoder) 
            % Чтение данных из COM-порта
                app.data = fread(app.s, 11); % Читаем 3 байта
                if ~isempty(app.data)
                    if app.data(1)==0x1C
                        int_value = typecast(uint8(flipud(app.data(2:3))), 'int16'); % Преобразуем данные
                        value = int_value / 1.39;
                        % Обновление данных графика
                        app.tDatareal = [app.tDatareal, length(app.tDatareal) * 0.016];
                        app.ADatareal = [app.ADatareal(2), value];
                        %app.xData = [app.xData(2) app.c+1]%[app.xData, length(app.xData) * 0.016384];
                        %app.yData = [app.timework app.timework+app.diskretTime]%[app.yData, value];
                        % Ограничение размера данных (например, последние 100 точек)
                        % if length(app.xData) > 100
                        %   app.xData = app.xData(2:end);
                        %   app.yData = app.yData(2:end);
                        % end
                        plot(app.AxesInputIncoder, app.t, app.ADatareal, 'blue');
                        app.t = [app.t(1)+app.diskretTime,app.t(2)+app.diskretTime];
                        % Обновление графика
                        %cla(app.AxesInputIncoder);
                        %cla(app.AxesInputIncoder);
                    end
                    if app.data(4)==0x2D
                        int_value = typecast(uint8(flipud(app.data(5:6))), 'uint16'); % Преобразуем данные;
                        app.u_datareal = [app.u_datareal(2), int_value];
                        plot(app.AxesOutput, app.t, app.u_datareal, 'black');
                        
                    end
                    if app.data(7)==0x2C
                        int_value = typecast(uint8(flipud(app.data(8:11))), 'int32'); % Преобразуем данные;
                        app.error_datareal = [app.error_datareal(2), int_value];
                        plot(app.AxesOutput, app.t, app.error_datareal, 'red');
                    end
                
                else 
                    
                    app.tDatareal = [0 0];
                    app.ADatareal = [0 0];    
                    plot(app.AxesOutput, app.t, app.u_datareal, 'black');
                    plot(app.AxesInputIncoder, app.t, app.ADatareal, 'blue');
                    app.t = [app.t(1)+app.diskretTime,app.t(2)+app.diskretTime];
                end
                
                    app.timework = app.timework + app.diskretTime;
                    app.c = app.c+0.01;
                    if app.isSinactived 
                        app.sinA = app.AMP*sin(app.t/app.T);
                        %cla(app.AxesInputIncoder);
                        
                        plot(app.AxesInputIncoder, app.t, app.sinA, 'r');
                        %drawnow
                    end

                    
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SettisgSin
        function SettisgSinPushed(app, event)

        end

        % Button down function: AxesInputIncoder
        function AxesInputIncoderButtonDown(app, event)

        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            app.isPaused = ~app.isPaused;
            if app.isPaused
                stop(app.Timer);
                app.Pause.Text = 'Возобновить работу';
            else
                start(app.Timer);
                app.Pause.Text = 'Приостановить работу';
            end
        end

        % Image clicked function: by_FORVARD
        function by_FORVARDImageClicked(app, event)
            
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
        
            
            % Проверка доступности COM-порта
            if ~isempty(instrfind('Port', 'COM6'))
                delete(instrfind);
                %error('Порт COM3 уже занят другим процессом.');
            else
            % Инициализация объекта serial
            app.s = serialport('COM6',38400); 
            %set(app.s, 'BaudRate', 38400, 'Timeout', 10, 'Terminator', '');
            % Открытие порта
            try
                fopen(app.s);
            catch ME
                %error(['Не удалось открыть порт COM6: ', ME.message]);
            end
            end

            % Инициализация таймера
            app.ADatareal = [0 0];
            app.tDatareal = [0 0];
            app.ADatasynzad = [0 0];
            app.tDatasinzad =[0 0];
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', app.diskretTime, ... 
                              'TimerFcn', @(src, event) figureupdate(app,  src, event));
            app.t = [0 app.diskretTime];
            start(app.Timer);
            app.Start.Text = 'Маятник запущен';
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.AMP = app.AEditField.Value;
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            app.T = app.TEditField.Value;
            
        end

        % Button pushed function: sinAxTButton
        function sinAxTButtonPushed(app, event)
            app.isSinactived = true;
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxesInputIncoder = uiaxes(app.UIFigure);
            title(app.AxesInputIncoder, 'график угла отклонения маятника')
            xlabel(app.AxesInputIncoder, 't,c')
            ylabel(app.AxesInputIncoder, 'A,град')
            zlabel(app.AxesInputIncoder, 'Z')
            app.AxesInputIncoder.ButtonDownFcn = createCallbackFcn(app, @AxesInputIncoderButtonDown, true);
            app.AxesOutput = uiaxes(app.UIFigure);
            title(app.AxesOutput, 'График управления')
            xlabel(app.AxesOutput, 't,с')
            ylabel(app.AxesOutput, 'U,В')
            zlabel(app.AxesOutput, 'Z')
            app.SetPID = uibutton(app.UIFigure, 'push');
            app.SetPID.Text = 'Отправить настройки';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Limits = [0 1000];
            app.PIDSetting = uilabel(app.UIFigure);
            app.PIDSetting.Text = 'Коэффициенты PID';
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Limits = [0 1000];
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.Limits = [0 1000];
            app.Settings = uilabel(app.UIFigure);
            app.Settings.Text = 'Настройки колебаний';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A,град';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditField.Limits = [-50 50];
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T,гц';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditField.Limits = [0.001 3];
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 0.001;
            app.SettisgSin = uibutton(app.UIFigure, 'push');
            app.SettisgSin.ButtonPushedFcn = createCallbackFcn(app, @SettisgSinPushed, true);
            app.SettisgSin.Text = 'Отправить настройки колебаний';
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Text = 'Приостановить работу';
            app.sinAxTButton = uibutton(app.UIFigure, 'push');
            app.sinAxTButton.ButtonPushedFcn = createCallbackFcn(app, @sinAxTButtonPushed, true);
            app.sinAxTButton.Text = 'Построить sinA(x/T)';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = 'Запустить маятник';
            app.by_FORVARD = uiimage(app.UIFigure);
            app.by_FORVARD.ImageClickedFcn = createCallbackFcn(app, @by_FORVARDImageClicked, true);
            app.by_FORVARD.ImageSource = fullfile(pathToMLAPP, 'cjh1yt1xkjmrnw1r.png');
            app.ProgrammName = uilabel(app.UIFigure);
            app.ProgrammName.Text = 'Управление маятником';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = 'Режим разраба';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_for_pendulum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end